classdef Vesicle_finding_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        Main_im                  matlab.ui.container.Panel
        axes3                    matlab.ui.control.UIAxes
        axes1                    matlab.ui.control.UIAxes
        uipanel24                matlab.ui.container.Panel
        text_Highpass            matlab.ui.control.Label
        edit_Highpass            matlab.ui.control.EditField
        pushbutton_nextName      matlab.ui.control.Button
        pushbutton_formerName    matlab.ui.control.Button
        pushbutton_LoadFile      matlab.ui.control.Button
        edit_Lowpass             matlab.ui.control.EditField
        textFilename             matlab.ui.control.Label
        uipanel23                matlab.ui.container.Panel
        slider_Edges             matlab.ui.control.Slider
        slider_Local             matlab.ui.control.Slider
        slider_Threshold         matlab.ui.control.Slider
        text31                   matlab.ui.control.Label
        text32                   matlab.ui.control.Label
        text33                   matlab.ui.control.Label
        togglebutton_Automask    matlab.ui.control.StateButton
        pushbutton_OutlineMask   matlab.ui.control.StateButton
        pushbutton_Invert        matlab.ui.control.Button
        pushbutton_Intersect     matlab.ui.control.Button
        pushbutton_Undo          matlab.ui.control.Button
        pushbutton_Redo          matlab.ui.control.Button
        togglebutton_Beam        matlab.ui.control.StateButton
        MaskRadiobuttons         matlab.ui.container.ButtonGroup
        radiobutton22            matlab.ui.control.RadioButton
        radiobutton24            matlab.ui.control.RadioButton
        radiobutton23            matlab.ui.control.RadioButton
        slider_dense             matlab.ui.control.Slider
        text38                   matlab.ui.control.Label
        edit_beamX               matlab.ui.control.EditField
        edit_beamY               matlab.ui.control.EditField
        edit_beamR               matlab.ui.control.EditField
        text40                   matlab.ui.control.Label
        text41                   matlab.ui.control.Label
        text42                   matlab.ui.control.Label
        togglebutton_PaintMask   matlab.ui.control.StateButton
        fixedCheckbox            matlab.ui.control.CheckBox
        text46                   matlab.ui.control.Label
        slider_GlobalVar         matlab.ui.control.Slider
        uipanel7                 matlab.ui.container.Panel
        edit_MinRadius           matlab.ui.control.EditField
        text28                   matlab.ui.control.Label
        text_MinRadius           matlab.ui.control.Label
        pushbutton_Find          matlab.ui.control.Button
        edit_MaxRadius           matlab.ui.control.EditField
        edit_RadiusStep          matlab.ui.control.EditField
        text36                   matlab.ui.control.Label
        text37                   matlab.ui.control.Label
        edit_MinAmp              matlab.ui.control.EditField
        edit_MaxAmp              matlab.ui.control.EditField
        text29                   matlab.ui.control.Label
        pushbutton_FindMore      matlab.ui.control.Button
        text45                   matlab.ui.control.Label
        edit_IceAmp              matlab.ui.control.EditField
        pushbutton_FindAndTrack  matlab.ui.control.Button
        pushbutton22             matlab.ui.control.Button
        togglebutton_RoboFit     matlab.ui.control.StateButton
        editWhite                matlab.ui.control.EditField
        editBlack                matlab.ui.control.EditField
        text43                   matlab.ui.control.Label
        text44                   matlab.ui.control.Label
        pushbutton_ShowVesicles  matlab.ui.control.Button
        pushbutton_AutoContrast  matlab.ui.control.Button
    end

    
    methods (Access = private)
        function CloseFile(app, h)
            if ~h.imageLoaded || ~h.miChanged
            %     disp('No changes to mi file.  Not written.');
                return
            end;
            
            if h.readOnly
                disp('Read-only mode.  No file written.');
                return
            end;
            
            % discard unused masks
            if isfield(h.mi,'mask')&&(numel(h.mi.mask)>h.maskIndex)
                h.mi.mask=h.mi.mask(1:h.maskIndex);
            end;
            % mi=h.mi;
            if h.sav.eraseOldPicks
                h.mi.particle.picks=[];
                mi=h.mi;
            else
                mi=rsMergeVesicleList2(h.mi,h.miOriginal);
            end;
            if numel(h.oldVesicleModel)>0  % Restore the old vesicle model
                mi.vesicleModel=h.oldVesicleModel;
            end;
            mi.log{end+1,1}=['Vesicle_finding_GUI ' TimeStamp];
            outName=[mi.infoPath mi.baseFilename 'mi.txt'];
            nameWritten=WriteMiFile(mi,outName);
            disp(['Saved: ' nameWritten]);
            disp(' ');
            h.miChanged=false;
            h.imageLoaded=false;
        end
        
        function h = CreateE1Map(app, h)
            %                           (if available) for automasking.
            %   Create the global "dense" mask
            if numel(h.exp1Image)>1 && ~any(isnan(h.exp1Image(:))) % Something there
                %     filter it, make a histogram, set the threshold.  We take the
                %     reference intensity of the "clear" part of the micrograph to be
                %     the upper half-maximum point of the histogram.
                exp1f=GaussFiltDCT(h.exp1Image,h.sav.automaskPars.denseFilt*h.pixA*2);
                med=abs(median(exp1f(:)));  % make sure it's positive, should be.
                bins=.75*med:med/200:1.25*med;
                e1Hist=hist(exp1f(:),bins);
                [e1Val,~]=max(e1Hist);
                e1Upper=find(e1Hist>e1Val/2,1,'last');
                %     sigma5=5*(e1Upper-e1Mode+.5);
                %     e1Ctr=bins(e1Mode);
                e1Ctr=bins(e1Upper); %%%%%%%%
                if h.sav.automaskFixed
                    e1Ctr=h.e1CtrValue;
                else
                    h.e1CtrValue=e1Ctr;
                end;
            
                %     sigma1=std(exp1f(abs(exp1f-e1Ctr)<sigma5));
                %     e1mx=e1Ctr+sigma1;
                %     e1mn=e1Ctr-10*sigma1;
                %     h.e1Map=(exp1f-e1mn)/(e1mx-e1mn);
                h.e1Map=(10*(exp1f/e1Ctr-.9)); % expand the top 10%
            else
                h.e1Map=ones(size(h.exp1Image),'single');  % all ones
            end;
            
            % Also create the varMap.
            f1=(h.pixA*2)/20;  % lowpass cutoff of bp filter for variance
            f2=(h.pixA*2)/60;  % highpass cutoff
            varFilt=(h.pixA*2)/400; % filtering of the variance
            workImage=DownsampleGeneral(h.rawImage,h.displaySize/2);
            varMap=GaussFiltDCT((SharpHP(SharpFilt(workImage,f1),f2)).^2,varFilt);
            
            mxVal=max(varMap(:));  % make sure it's positive, should be.
            bins=0:mxVal/100:mxVal;
            varHist=hist(varMap(:),bins);
            [~,modeIndex]=max(varHist);
            varMode=bins(modeIndex);
            h.varMap=varMap/(10*varMode);
        end
        
        function DoFind(app, hObject, eventdata, h)
            
            if ~h.imageLoaded
                return
            end;
            
            set(h.figure1,'pointer','watch');
            % membrane model
            vLipid=h.sav.membranePars(1);
            thk=h.sav.membranePars(2);
            rise=h.sav.membranePars(3);
            pixA=h.mi.pixA;
            
            % Create the model, which is sampled in units of the original pixel size.
            nm0=ceil(thk/(2*pixA))*2+3;  % array for vesicle model; 60A nominal
            if ~isfield(h.mi,'vesicleModel') || numel(h.mi.vesicleModel)<3
                h.mi.vesicleModel=fuzzymask(nm0,1,thk/pixA/2,rise/pixA)...
                    *vLipid;  % units of V
            end;
            rPars=h.sav.vesicleRadii;
            % Zero out the previous picks
            mi1=h.mi;
            mi1.vesicle.x=[];
            mi1.vesicle.y=[];
            mi1.vesicle.r=[];
            mi1.vesicle.s=[];
            mi1.vesicle.ok=[];
            if isfield(mi1,'mask')
                mi1.mask=mi1.mask(1:h.maskIndex); % collapse the mask array
            end;
            mi1=rsFindVesicles3(h.rawImage, mi1, rPars, h.findInMask);
            %%
            minAmp=h.sav.vesicleAmps(1);
            maxAmp=h.sav.vesicleAmps(2);
            
            mins=1;
            nVesOld=0;
            % Loop through finding groups of 50 vesicles.
            while mins>minAmp
                [mi1, t]=rsFindVesicles3('next',50,h.sav.vesicleAmps);
                axes(h.axes1);
                ih = imshow(rot90(uint8(imscale(t.ms-t.umodel,256,1e-3))),...
                    'InitialMagnification',100*size(t.ms,1)/size(h.rawImage,1) );
                %     drawnow;
                mins=t.globalmax;
                nves=size(mi1.vesicle.s,1);
                set(hObject,'string',num2str(nves));
                %     Make the scatterplot of vesicle radii and amplitudes
                xs1=rPars(1);
                xs2=rPars(2);
                xs=[xs1    xs1    xs2    xs2    xs1];
                ys=[minAmp maxAmp maxAmp minAmp minAmp];
                if numel(mi1.vesicle.ok)<4  % not even one row present
                    mi1.vesicle.ok=false(1,4); % make sure there are enough elements
                end;
                goodVes=all(mi1.vesicle.ok(1:nves,1:2),2);
                badVes=mi1.vesicle.ok(1:nves,1) & ~mi1.vesicle.ok(1:nves,2);
                %     plot(h.axes3,mi1.vesicle.r(1:nves)*mi1.pixA,mi1.vesicle.s(1:nves),...
                %         'b.', xs,ys,'r-','markersize',10);
                if numel(mi1.vesicle.r)>0  % something to draw
                    plot(h.axes3,mi1.vesicle.r(goodVes,1)*mi1.pixA,mi1.vesicle.s(goodVes,1),'b.',...
                        mi1.vesicle.r(badVes,1)*mi1.pixA,mi1.vesicle.s(badVes,1),'m.',...
                        xs,ys,'k-','markersize',10);
                else
                    cla(h.axes3);
                end;
                title(nves);
                %     Exit the loop when we can't find any more vesicles
                if nves<=nVesOld
                    break
                end;
                nVesOld=nves;
            end;
            h.ccVals=t.ccsmx;
            h.ccValsScaled=t.ccsmxScaled;
            h.ccRadii=(t.fitmin+(t.ccsmi-1)*t.rstep)*t.ds;  % radius in orig pixels
            
            mi1.vesicle.shiftX=[];
            mi1.vesicle.shiftY=[];
            mi1.vesicle.shiftOk=[];
            mi1.vesicle.af=[];
            mi1.vesicle.refined=0;
            mi1.vesicle.extraPeaks=[];
            mi1.vesicle.extraSD=0;
            mi1.vesicle.extraS=[];
            h.mi=mi1;
            h.miChanged=1;
            if h.makeModelVesicles
                h.goodVesImage=meMakeModelVesicles(h.mi,size(h.rawImage),find(goodVes));
                h.badVesImage=meMakeModelVesicles(h.mi,size(h.rawImage),find(badVes));
                h.rawVesImage=h.goodVesImage+h.badVesImage;
                % h.rawVesImage=meMakeModelVesicles(h.mi,size(h.rawImage));
                h=UpdateDisplayFiltering(h);
            end;
            % h.displayMode=0;  % mark the vesicles
            h.markedVesicleIndex=0;
            ShowImage(h);
            drawnow;
            
            if h.doTrackMembranes
            
                % Tune up the vesicle fits
                disp('Tracking vesicle membranes');
            
                h.mi=TrackVesicleMembrane(h);
            
                % display everything again
                if h.makeModelVesicles
                    disp('Computing vesicle models');
                    goodVes=all(h.mi.vesicle.ok(:,1:2),2); % vesicles in range
                    badVes=(h.mi.vesicle.ok(:,1) & ~h.mi.vesicle.ok(:,2)); % found, but not in range
                    h.goodVesImage=meMakeModelVesicles(h.mi,size(h.rawImage),find(goodVes));
                    h.badVesImage=meMakeModelVesicles(h.mi,size(h.rawImage),find(badVes));
                    h.rawVesImage=h.goodVesImage+h.badVesImage;
                    % h.rawVesImage=meMakeModelVesicles(h.mi,size(h.rawImage));
                    h=UpdateDisplayFiltering(h);
                end;
                ShowImage(h);
                drawnow;
            
            end;
            
            guidata(hObject,h);
            set(hObject,'string','Find');
            set(h.figure1,'pointer','arrow');
            % disp('Done.');
        end
        
        function FileNameText_Callback(app, hObject, eventdata, h)
            % hObject    handle to FileNameText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % h    structure with h and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of FileNameText as text
            %        str2double(get(hObject,'String')) returns contents of FileNameText as a double
        end
        
        function FileNameText_CreateFcn(app, hObject, eventdata, h)
            % hObject    handle to FileNameText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % h    empty - h not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function h = GetFileList(app, h)
            % Put up a file selector and pick one mi file. Change directory to the one above where the mi file sits.
            % Also create a list of all mi
            % files in that directory, which will allow moving forward and backward.
            % h.fileIndex points to the name you selected in the
            % whole h.fileList (cell array of strings.)
            
            % Check to see if we have a starting file.
            if exist(h.sav.startPath,'dir') ...
                    && exist([AddSlash(h.sav.startPath) h.sav.startFile],'file')==2
                basePath=h.sav.startPath;
                [infoPath,nm,ex]=fileparts(h.sav.startFile);
                nm=[nm ex];
            else % put up the file selector
                disp('Getting an mi.txt file');
                [nm,pa]=uigetfile('*mi.txt','Get an mi file.');
                if isnumeric(pa) % user clicked Cancel
                    return
                end;
                [basePath,infoPath]=ParsePath(pa);
            end;
            
            cd(basePath);
            % Build up the file list
                h.fileList={};
                h.fileIndex=1;
            d=dir(infoPath);
            for i=1:numel(d);
                if strndcmp(d(i).name,'mi.txt')
                    name=[AddSlash(infoPath) d(i).name];
                    h.fileList=[h.fileList; {name}];
                    if strcmp(d(i).name,nm) % look for the one we selected
                        h.fileIndex=numel(h.fileList);
                    end;
                end;
            end;
        end
        
        function [h, ok] = GetImageFile(app, mi, h)
            % Having loaded an mi file, load the image and initialize variables.
            
            set(h.textFilename,'string','---');
            % set(h.togglebutton_Beam,'value',0);
            set(h.togglebutton_Automask,'value',0);
            set(h.togglebutton_PaintMask,'value',0);
            h.imageLoaded=false;
            
            % update the vesicle info to the canonical nv x 4 logical matrix
            nv=numel(mi.vesicle.x);
            if ~(isfield(mi.vesicle,'ok')...
                    && size(mi.vesicle.ok,1)==nv)
                mi.vesicle.ok=true(nv,1);
            end;
            if size(mi.vesicle.ok,2)<4 && nv>0
                mi.vesicle.ok(nv,4)=false;  % extend the array
            end;
            
            % Load the merged image
            imageBasename=[mi.procPath mi.baseFilename 'm.mrc'];
            % ok=0;
            %
            % for i=1:numel(h.imageFileTypes)
            %     [fullImageName,ok]=CheckForImageOrZTiff([imageBasename h.imageFileTypes{i}]);
            %     if ok
            %         break;
            %     end;
            % end;
            sufExts={'s.mrc' 'z.tif' '.mrc'};
            [fullImageName,ok]=CheckForAltImage(imageBasename,sufExts);  % valid filename?  Load it
            h.oldFilterFreqs=[0 0];
            h.baseName=imageBasename;
            
            set(h.textFilename,'string',mi.baseFilename);
            
            if ok
                %     h.origImage=ReadEMFile(fullImageName)/mi.doses(1);  % normalize by dose.
                if strcmp(fullImageName(end-4:end),'z.tif')
                    disp('Reading the compressed merged image');
                end;
                h.origImage=ReadEMFile(fullImageName);
            
                %     Pick up the first exposure for use in masking
                exp1name=mi.imageFilenames{1};  % get the low-defocus image
                [exp1name2,ok1]=CheckForImageOrZTiff([mi.imagePath exp1name]);
                if ok1 && h.useFirstExposure  % pick up the first exposure and make it the same size.
                    if ~strcmp(exp1name2(end-4:end),'z.tif')
                        disp('Reading the first exposure');
                    else
                        disp('Reading the compressed first exposure');
                    end;
                    e1Image=ReadEMFile(exp1name2);
                    h.e1ImageOffset=0;
                else
                    if h.useFirstExposure
                        disp(['First exposure not found: ' exp1name]);
            %             disp(' ...using merged image for an inferior global mask.');
                    end;
                    h.e1ImageOffset=5;
                    e1Image=h.origImage+h.e1ImageOffset;  % offset it from zero.
                end;
                h.exp1Image=DownsampleGeneral(e1Image,h.displaySize/2);
            
                h.imageLoaded=true;
                h.rawImage=single(0);
                h.automaskOn=false;
                set(h.togglebutton_Automask,'value',false);
                %     Clear all the processed images.
                h.filtImage=single(0);
                h.rawVesImage=single(0);
                h.filtVesImage=single(0);
                %     cla(h.axes3);  % trying to get around bug in Matlab 2015a
            
                % initialize the mask
                t=mi.mergeMatrix;
                msk=meMakeMergedImageMask(mi.imageSize/4,t,mi.imageSize/(4*h.borderFraction));
                mi=meInsertMask(msk,mi,1);
                %     if h.automaskBeamOn  % we've left this on from last time; update values
                %         mi.mask(2).merge='AND';
                %         mi.mask(2).encoding='RIM';
                %         mi.mask(2).data=h.sav.beamPars/100;
                %     end;
                %     Point to the end of the stack
                h.maskIndex=numel(mi.mask);
            
                %   Initialize variables
                h.oldVesicleModel=mi.vesicleModel;
                h.miOriginal=mi;
                h.mi=mi;  % -------copy the mi structure here------
                h.ccVals=0;
                h.ccValsScaled=0;
                %     h.ccRadii=0;
                h.markedVesicleIndex=0;
                h.ifImageComp=zeros(256,'single');
                %     h.ifImageFlat=zeros(256,'single');
                %     if h.maskIndex<3 % no audomasking has been done
                %         set(h.togglebutton_Automask,'value',true);
                %     end;
            % else
            %     warning(['Can''t find the image ' imageBasename]);
            end;
        end
        
        function h = InitAutomask(app, h)
            h.maskIndex=max(h.maskIndex,3);  % Show this mask
            m=h.rawImage-h.goodVesImage-h.badVesImage;
            m=Downsample(m,size(m)/2);
            h.ifImage=meCTFInverseFilter(m,h.mi,1,0,0);  % totally inverse filtered
            % h.ifImageFlat=GaussFilt(meCTFInverseFilter(m,h.mi,1,0,.0005),.05);
            h.ifImageComp=GaussFilt(meCTFInverseFilter(m,h.mi,1,.0005,.0005),.1);
            
            % q=gcf;
            % figure(2);
            % subplot(221); imags(h.ifImageFlat);
            % subplot(222); imags(h.ifImageComp);
            % figure(q);
            % pwd
            % save('h.mat','h');
            
            %
            % h.automaskLims=zeros(1,4);
            % % h.automaskLims(1:2)=Place(h.ifImageFlat(:),[.01 .8]);
            % h.automaskLims(3:4)=Place(h.ifImageComp(:),[.01 .8]);
            if ~isfield(h.mi,'mask')
                h.mi.mask=struct('merge',[],'encoding',[],'data',[]);
            end;
        end
        
        function h = InitDisplay(app, h)
            if ~h.imageLoaded
                return
            end;
            % set(h.figure1,'pointer','watch');
            osize=size(h.origImage);
            %         Get the downsampling factor from the loaded image, and from the
            %         original micrograph.
            h.dsImage=(max(osize./h.displaySize)); % Force image to fit
            h.ds0=h.dsImage*h.mi.imageSize(1)/osize(1);
            h.pixA=h.ds0*h.mi.pixA; % scale of displayed image.
            
            %         Create the displayed image
            h.rawImage=DownsampleGeneral(h.origImage,osize(1)/h.dsImage);
            
            h=CreateE1Map(h);
            
            if h.maskIndex<3  % Turn off automask if it hasn't been done before.
                set(h.togglebutton_Automask,'value',false);
            end;
            
            if h.makeModelVesicles
                if ~isfield(h.mi.vesicle,'ok') || size(h.mi.vesicle.ok,1)<1
                    h.mi.vesicle.ok=false;  % make one entry.
                else % something these
                    nv=size(h.mi.vesicle.ok,1);
                    disp(['Making ' num2str(nv) ' model vesicle' ess(nv)]);
                end;
                ne=size(h.mi.vesicle.ok,2);
                if ne<4
                    h.mi.vesicle.ok(1,4)=false;  % extend it.
                end;
            
                goodVes=all(h.mi.vesicle.ok(:,1:2),2); % vesicles in range
                badVes=(h.mi.vesicle.ok(:,1) & ~h.mi.vesicle.ok(:,2)); % found, but not in range
            
                h.goodVesImage=meMakeModelVesicles(h.mi,size(h.rawImage),find(goodVes));
                h.badVesImage=meMakeModelVesicles(h.mi,size(h.rawImage),find(badVes));
            %     disp('...done');
            end;
            h.ctf=meGetEffectiveCTF(h.mi,size(h.rawImage),h.ds0);
            h=UpdateDisplayFiltering(h);
            h=UpdateAutomaskBeam(h);  % Also calls ShowImage.
            
            % Get the figure handle, starting with the axes handle.
            fh=h.axes1;
            str=get(fh,'Type');
            while ~strcmpi(str,'figure')
                fh=get(fh,'Parent');
                str=get(fh,'Type');
            end;
            
            % Set up the keypress function
            fhndl=@(hObject,eventdata) Vesicle_finding_GUI('KeyPressFcn',hObject,eventdata,guidata(hObject));
            set(fh,'keypressfcn',fhndl);
        end
        
        function h = InsertPaintedMask(app, h)
            if numel(h.manualMaskCoords)<2  % nothing drawn, remain active.
                return
            end;
            % We're done painting a mask.  First turn off the button
            set(h.togglebutton_PaintMask,'value',0);
            h.manualMaskActive=0;
            n=round(size(h.rawImage)/2);  % make a half-sized mask
            msk=false(n);
            r=h.manualMaskDiameter/4;
            coords=round((h.manualMaskCoords+1)/2);
            h.manualMaskCoords=zeros(0,2,'single');
            npts=size(coords,1);
            
            ru=ceil(r*2+1);
            template=single(disc(ru,r));
            
            for i=1:npts
                msk1=ExtractImage(template,coords(i,:),n,1);
                msk=msk | msk1;
            end;
            msk=fliplr(msk);
            h.maskIndex=max(3,h.maskIndex)+1;
            h.mi=meInsertMask(~msk,h.mi,h.maskIndex,'AND');
            h.miChanged=1;
            h=ShowImage(h);
        end
        
        function KeyPressFcn(app, hObject, eventdata, h)
            figure(gcbf);
            char=get(gcbf,'CurrentCharacter');
            switch char
                case {'d' ' '}
                    h.displayMode=h.displayMode+1;
                    if h.displayMode>h.maxDisplayMode
                        h.displayMode=0;
                    end;
                    h=ShowImage(h);
            end;
            guidata(hObject,h);
        end
        
        function [h, ok] = LoadAFile(app, h)
            CloseFile(h);
            ok=false;
            h.imageLoaded=false;
            if h.fileIndex<1 || h.fileIndex>numel(h.fileList)
                beep;
                disp('No file loaded.');
                return
            else
                fileName=h.fileList{h.fileIndex};
                if ~exist(fileName,'file');
                    disp(['File not found: ' fileName]);
                    return
                end;
            end;
            disp(['Reading ' fileName]);
            mi=ReadMiFile(fileName);  % loads mi
            nim=max(1,min(min(numel(mi.ctf),numel(mi.doses)),size(mi.frameSets,1)));
            for i=1:nim
                disp([num2str(mi.ctf(i).defocus,3) 'um.  frames: ' num2str(mi.frameSets(i,:)) '  dose: ' num2str(mi.doses(i),3)]);
            end;
            h.sav.baseName=mi.baseFilename;
            
            if h.sav.eraseOldPicks % was initTheVesicles
                mi=ZeroOutVesicles(mi,h);
            end;
            
            [h, ok]=GetImageFile(mi,h);
            if ok
                h.imageLoaded=true;
                h=InitDisplay(h);
            else
                disp('Image couldn''t be loaded');
            end;
        end
        
        function MaskRadiobuttonsCallback(app, hObject, eventdata, h)
            p=get(hObject,'SelectedObject');
            val=get(p,'string');
            h=guidata(hObject);  % We'll pick up first 3 digits of the string.
            h.manualMaskDiameter=str2double(val(1:3))/h.pixA;
            guidata(hObject,h);
        end
        
        function h = NewAutomask(app, h, active)
            if nargin<2
                active=true;
            end;
            if ~active
                h.mi=meInsertMask(true(round(size(h.rawImage)/2)),h.mi,3,'AND');
                h.miChanged=1;
                h=ShowImage(h);
                return
            end;
            if h.automaskOn
                %     thr1=h.sav.automaskPars.thresh*(h.automaskLims(2)-h.automaskLims(1))...
                %         +h.automaskLims(1);
                thr2=2*h.sav.automaskPars.width-2; % local mask threshold
                thr3=h.sav.automaskPars.dense;     % global mask threshold
                mskLocal=h.ifImageComp>thr2;
                mskGlobal= (h.e1Map>thr3) & (h.varMap<(1-h.sav.automaskPars.var));
                fc1=exp(-h.sav.automaskPars.edge*3); % local mask smoothness
                fc2=fc1/5;
                if fc1<.9
                    mskLocal=GaussFilt((GaussFilt(mskLocal,fc1)>.1),fc2)>.9;
                end;
                fc1=exp(-h.sav.automaskPars.thresh*3)*.1; % global mask border
                mskGlobal=(GaussFiltDCT(mskGlobal,fc1)>.99);
            
                % The automask always goes into position 3.
                h.mi=meInsertMask(mskLocal & mskGlobal,h.mi,3,'AND');
                h.miChanged=1;
                h=ShowImage(h);
            end;
        end
        
        function vals = Place(app, m, pctls)
            np=numel(m);
            [sortedM]=sort(m(:));
            nv=numel(pctls);
            vals=zeros(1,nv);
            for i=1:nv
                ptr=round((np-1)*pctls(i)+1); % Get the index
                vals(i)=sortedM(max(1,min(ptr,numel(sortedM))));
            end;
        end
        
        function h = ShowImage(app, h)
            if h.imageLoaded
            
                % Draw the scatterplot of existing vesicles
                if isfield(h.mi.vesicle,'ok')
                    [nv, ne]=size(h.mi.vesicle.ok);
                    if nv>0 && ne>3 && numel(h.mi.vesicle.r)>0
                        goodVes=all(h.mi.vesicle.ok(:,1:2),2); % vesicles in range
                        badVes=(h.mi.vesicle.ok(:,1) & ~h.mi.vesicle.ok(:,2)); % found, but not in range
                        plot(h.axes3,...
                            h.mi.vesicle.r(goodVes,1)*h.mi.pixA,h.mi.vesicle.s(goodVes,1),'b.',...
                            h.mi.vesicle.r(badVes,1)*h.mi.pixA,h.mi.vesicle.s(badVes,1),'m.',...
                            'markersize',15);
                        if h.markedVesicleIndex>0
                            set(h.axes3,'nextplot','add');
                            plot(h.axes3,h.mi.vesicle.r(h.markedVesicleIndex,1)*h.mi.pixA,...
                                h.mi.vesicle.s(h.markedVesicleIndex,1),'ks','markersize',12);
                            set(h.axes3,'nextplot','replace');
                        end;
                        title(h.axes3,[num2str(sum(goodVes)) ' vesicles in range']);
                    else
                        title(h.axes3,' '); % clear the title
                        goodVes=false;
                        badVes=false;
                    end;
                end;
                nv=numel(h.mi.vesicle.x);
            
                % if numel(h.filtImage)>0  % an image has been loaded
                msk=rot90(meGetMask(h.mi,size(h.filtImage),1:h.maskIndex));
                imData=h.filtImage;
            %     n=size(h.filtImage);
                showMask=1;
                showAmps=1;
                showCircles=1;
                showGhosts=0;
                switch h.displayMode
                    case 0 % default
                        imData=h.filtImage;
                    case 1
                        imData=h.filtImage-h.filtVesImage;
                        showAmps=0;
                    case 2
                        imData=h.filtImage-h.filtVesImage;
                        showGhosts=h.makeModelVesicles;
                        showCircles=0;
                        showAmps=1;
            %         case 3
            %             if numel(h.ccValsScaled)>1
            %                 imData=h.ccValsScaled(1:n(1),1:n(2));
            %                 showGhosts=h.makeModelVesicles;
            %             else
            %                 return
            %             end;
                    case 3
                        imData=Downsample(h.ifImageComp,size(h.filtImage));
                        showGhosts=h.makeModelVesicles;
                        showCircles=0;
                end;
                %     theImage =  repmat(rot90(imscale(imData,256,1e-3)),[1 1 3]);
            %     midValue=(h.e1CtrValue-h.e1ImageOffset)/(h.mi.doses(1)*h.mi.cpe)
                midValue=0;
            %%
            theImage =  repmat(rot90(256*(imData-midValue-h.sav.black)/(h.sav.white-h.sav.black)+128),[1 1 3]);
                nx=size(h.rawImage,1);
                ny=size(h.rawImage,2);
            
                if showGhosts
                    ghostColor=[.7 .7 1];
                    ghostColorBad=[1 .5 .35];
                    dotW=2;
            
                    xCtrs=max(dotW+1,min(nx-dotW,round(h.mi.vesicle.x/h.ds0+1)));
                    yCtrs=max(dotW+1,min(ny-dotW,round(h.mi.vesicle.y/h.ds0+1)));
                    if any(goodVes)
                        gves=imscale(max(-h.goodVesImage,-1e-9),1);
                        for i=find(goodVes)'
                            gves(xCtrs(i)-dotW:xCtrs(i)+dotW,yCtrs(i)-dotW:yCtrs(i)+dotW)=1.5;
                        end;
                    else
                        gves=0;
                    end;
                    if any(badVes)
                        bves=imscale(max(-h.badVesImage,-1e-9),1);
                        for i=find(badVes)'
                            bves(xCtrs(i)-dotW:xCtrs(i)+dotW,yCtrs(i)-dotW:yCtrs(i)+dotW)=1.5;
                        end;
            
                        %             hp1=plot(h.mi.vesicle.x(goodVes)/h.ds0+1,...
                        %             ny-(h.mi.vesicle.y(goodVes)/h.ds0),'b.','markersize',10);
            
                    else
                        bves=0;
                    end;
            
                    color=(1-ghostColor); % color to subtract for membrane
                    for i=1:3
                        theImage(:,:,i)=theImage(:,:,i).*(1-rot90(gves)*color(i));
                    end;
                    color=(1-ghostColorBad); % color for bad vesicle
                    for i=1:3
                        theImage(:,:,i)=theImage(:,:,i).*(1-rot90(bves)*color(i));
                    end;
                end;
                if showMask
                    maskColor=[1 .8 .85];
            
                    color=(1-maskColor);
                    for i=1:3
                        theImage(:,:,i)=theImage(:,:,i).*(1-(1-msk)*color(i));
                    end;
                end;
                %     --------draw the image------
                axes(h.axes1);
                cla reset
                ih = imshow(uint8(theImage),'InitialMagnification',100,'parent',h.axes1);
                set(h.axes1,'xLimMode','manual');
                set(h.axes1,'yLimMode','manual');
            
                %     if showGhosts % draw the center points of the vesicles
                %         %         goodCenterColor=[.5 .5 1];
                %         %         badCenterColor=[1 .5 1];
                %         if isfield(h.mi.vesicle,'ok') && size(h.mi.vesicle.ok,2)>3
                %             badVes=h.mi.vesicle.ok(:,1) & ~h.mi.vesicle.ok(:,2);
                %             goodVes=all(h.mi.vesicle.ok(:,1:2),2);
                %         else
                %             badVes=false(numel(h.mi.vesicle.x),1);
                %             goodVes=true(numel(h.mi.vesicle.x),1);
                %         end;
                %         hold on;
                %         ny=size(h.rawImage,2);
                %         hp1=plot(h.mi.vesicle.x(goodVes)/h.ds0+1,...
                %             ny-(h.mi.vesicle.y(goodVes)/h.ds0),'b.','markersize',10);
                %         hp2=plot(h.mi.vesicle.x(badVes)/h.ds0+1,...
                %             ny-(h.mi.vesicle.y(badVes)/h.ds0),'r.','markersize',10);
                %         hold off;
                %         set(hp1,'HitTest','off');
                %         set(hp2,'HitTest','off');
                %     end;
                if showAmps
                    for i=1:nv
                        x=double(h.mi.vesicle.x(i)/h.ds0+1);
                        y=double(ny-h.mi.vesicle.y(i)/h.ds0+1);
                        amp=h.mi.vesicle.s(i,1)*1000;
                        text(x,y,num2str(amp,2),'color',[.8 .8 0],'fontsize',12,'PickableParts','none');
                    end;
                end;
            
                if showCircles
                    hold on
                    for i=1:nv
                        r1=h.mi.vesicle.r(i,:)/h.ds0;
                        if r1(1)<1
                            continue
                        end;
                        [x,y]=CircleLineSegments(r1,min(10,100/r1(1)));
                        x=double(x+h.mi.vesicle.x(i)/h.ds0+1);
                        y=double(y+ny-h.mi.vesicle.y(i)/h.ds0+1);
                        if goodVes(i)
                            plot(x,y,'b-','HitTest','off');
                        elseif badVes(i)
                            plot(x,y,'r-','HitTest','off');
                        end;
                    end;
                    hold off
                end;


                fhndl=@(hObject,eventdata) Vesicle_finding_GUI('axes1_ButtonDownFcn',hObject,eventdata,guidata(hObject));
                set(ih,'buttondownfcn',fhndl);
                set(ih,'HitTest','on');
                h.ih=ih;
                h.theImage=uint8(theImage);
            
            end
        end
        
        function h = UpdateAutomaskBeam(app, h, active)
            % The beam mask always goes in index 2.
            if nargin>1
                h.automaskBeamOn=active;
            end;
            if h.automaskBeamOn
                if ~isfield(h.mi,'mask')
                    h.mi.mask=struct('merge',[],'encoding',[],'data',[]);
                end;
                h.mi.mask(2).merge='AND';
                h.mi.mask(2).encoding='RIM';
                h.mi.mask(2).data=h.sav.beamPars/100;
                h.miChanged=1;
                h=ShowImage(h);
            else
                h.mi.mask(2).merge=[];
                h=ShowImage(h);
            end;
        end
        
        function h = UpdateDisplayFiltering(app, h, doRawImage)
            if nargin<2
                doRawImage=1;
            end;
            h.filtImage=h.rawImage;
            h.filtVesImage=h.goodVesImage+h.badVesImage;
            
            fcs=h.sav.filterFreqs*h.pixA;
            if fcs(1)  % highpass freq not zero
                if doRawImage
                    h.filtImage=GaussHP(h.filtImage,fcs(1));
                end;
                h.filtVesImage=GaussHP(h.filtVesImage,fcs(1));
            end;
            if fcs(2)  % lowpass freq not zero
                if doRawImage
                    h.filtImage=GaussFilt(h.filtImage,fcs(2));
                end;
                h.filtVesImage=GaussFilt(h.filtVesImage,fcs(2));
            end;
            h.oldFilterFreqs=h.sav.filterFreqs;
        end
        
        function WindowButtonMotionFcn(app, hObject, eventdata)
            h=guidata(hObject);
            if h.axes1ButtonDown && h.manualMaskActive && h.manualMaskDiameter>0
                pt=get(h.axes1,'currentpoint');
                h.manualMaskCoords(end+1,:)=pt(1,1:2);
                %         if numel(h.theImage)>0
                %             n=size(h.theImage);
                %             msk=(1-uint8(disc(n,50,pt(1,:))))';
                %             h.theImage(:,:,1)=h.theImage(:,:,1).*msk;
                %                 axes(h.axes1);
                %             imshow(h.theImage,'InitialMagnification',100);
                %         end;
                set(h.axes1,'nextplot','add');
                plot(h.axes1,pt(1,1),pt(1,2),'o','markersize',h.manualMaskDiameter,'markerfacecolor','b');
                set(h.axes1,'nextplot','add');
            
                %         pt=get(h.axes3,'currentpoint');
                %         disp(pt);
                guidata(hObject,h);
            end
        end
        
        function WindowButtonUpFcn(app, hObject, eventdata)
            h=guidata(hObject);
            
            if h.manualMaskActive && h.manualMaskDiameter>0
                h=InsertPaintedMask(h);
            end;
            
            h.axes1ButtonDown=false;
            guidata(hObject,h);
            % pt=get(hObject,'currentpoint');
            % disp(pt);
        end
        
        function mis = ZeroOutVesicles(app, mis, h)
            % Initialize all the vesicle fields in the mi structure
            % membrane model
            vLipid=h.sav.membranePars(1);
            thk=h.sav.membranePars(2);
            rise=h.sav.membranePars(3);
            pixA=mis.pixA;
            % Create the model, which is sampled in units of the original pixel size.
            nm0=ceil(thk/(2*pixA))*2+3;  % array for vesicle model; 60A nominal
            mis.vesicleModel=fuzzymask(nm0,1,thk/pixA/2,rise/pixA)...
                *vLipid;  % units of V
            % Zero out the previous picks
            mis.vesicle.x=[];
            mis.vesicle.y=[];
            mis.vesicle.r=[];
            mis.vesicle.s=[];
            mis.vesicle.ok=[];
            mis.vesicle.shiftX=[];
            mis.vesicle.shiftY=[];
            mis.vesicle.shiftOk=[];
            mis.vesicle.af=[];
            mis.vesicle.refined=0;
            mis.vesicle.extraPeaks=[];
            mis.vesicle.extraSD=0;
            mis.vesicle.extraS=[];
        end
        
        function arrow = arrowbutton(app)
            
            arrow = iconize(imread('arrow.jpg'));
            arrow(arrow==255) = .8*255;
        end
        
        function doRobofit(app, hObject, h)
            disp(['RoboFit starting. ' datestr(now)]);
            % Robo-fit loop
            %     We check the state of the button after loading each file.
            while h.imageLoaded && get(h.togglebutton_RoboFit,'value')
                %         delete the old automask
                h=NewAutomask(h,false);
                set(h.togglebutton_Automask,'value',0);
                h.doTrackMembranes=0;
                %         guidata(hObject,h);
                %         Find vesicles
                DoFind(h.pushbutton_Find, 0, h);
                h=guidata(hObject);
                pause(0.1);
                pushbutton_FindMore_Callback(h.pushbutton_FindMore,0,h);
                h=guidata(hObject);
                pause(0.1);
                %         Make a new automask
                disp('Automask on.');
                set(h.togglebutton_Automask,'value',1);
                h=guidata(hObject);
                togglebutton_Automask_Callback(h.togglebutton_Automask, 0, h)
                %         %
                %         %         set(h.togglebutton_Automask,'value',0);
                %         Find vesicles again
                h=guidata(hObject);
                h.doTrackMembranes=h.roboTrackMembranes;
                DoFind(h.pushbutton_Find, 0, h)
                h=guidata(hObject);
                pause(0.1);
                %     pushbutton_FindMore_Callback(h.pushbutton_FindMore,0,h);
            
                %         Make a new automask
                set(h.togglebutton_Automask,'value',1);
                h=guidata(hObject);
                togglebutton_Automask_Callback(h.togglebutton_Automask, 0, h)
                pause(0.1);
                h=guidata(hObject);
                if h.batchMode
                    print('-djpeg',[h.jpegDir h.mi.baseFilename 'vf.jpg']);
                end;
                h.fileIndex=h.fileIndex+1;
                h=LoadAFile(h);
            end;
                    disp(['RoboFit ended. ' datestr(now)]);
            guidata(hObject,h);
        end
        
        function edit_Edge_Callback(app, hObject, eventdata, h)
            val=str2double(get(hObject,'String')); % returns contents of edit_Edge as a double
            if val>0 && val<40
                h.sav.membranePars(3)=val;
                guidata(hObject,h);
            end;
        end
        
        function edit_Edge_CreateFcn(app, hObject, eventdata, h)
            % hObject    handle to edit_Edge (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % h    empty - h not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_Thickness_Callback(app, hObject, eventdata, h)
            val=str2double(get(hObject,'String')); % returns contents of edit_Edge as a double
            if val>=10 && val<=100
                h.sav.membranePars(2)=val;
                guidata(hObject,h);
            end;
        end
        
        function out = iconize(app, a)
            % Find the size of the acquired image and determine how much data will need
            % to be lost in order to form a 18x18 icon
            [r,c,d] = size(a);
            r_skip = ceil(r/9);
            c_skip = ceil(c/9);
            % Create the 18x18 icon (RGB data)
            out =  a(1:r_skip:end,1:c_skip:end,:);
        end
        
        function invarrow = inarrowbutton(app)
            
            invarrow = iconize(imread('inverse_arrow.jpg'));
            invarrow(invarrow==255) = .8*255;
        end
        
        function pushbutton_AdjustContrast_Callback(app, ~, ~, h)
            if h.ih  % The image has been initialized
                imcontrast(h.axes1);
            end
        end
        
        function pushbutton_AdjustContrast_KeyPressFcn(app, hObject, eventdata, h)
            % hObject    handle to pushbutton_AdjustContrast (see GCBO)
            % eventdata  structure with the following fields (see UICONTROL)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % h    structure with h and user data (see GUIDATA)
        end
        
        function pushbutton_DeleteAll_Callback(app, hObject, eventdata, h)
            % hObject    handle to pushbutton_DeleteAll (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % h    structure with h and user data (see GUIDATA)
        end
        
        function pushbutton_Delete_Callback(app, hObject, eventdata, h)
            % hObject    handle to pushbutton_Delete (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % h    structure with h and user data (see GUIDATA)
        end
        
        function pushbutton_LoadModel_Callback(app, hObject, eventdata, h)
            % hObject    handle to pushbutton_LoadModel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
        end
        
        function pushbutton_Revert_Callback(app, hObject, eventdata, h)
            % hObject    handle to pushbutton_Revert (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % h    structure with h and user data (see GUIDATA)
        end
        
        function pushbutton_Select_Callback(app, hObject, eventdata, h)
            % hObject    handle to pushbutton_select (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % h    structure with h and user data (see GUIDATA)
        end
        
        function pushbutton_Select_KeyPressFcn(app, hObject, eventdata, h)
            % hObject    handle to pushbutton_select (see GCBO)
            % eventdata  structure with the following fields (see UICONTROL)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % h    structure with h and user data (see GUIDATA)
        end
        
        function slider10_Callback(app, hObject, eventdata, h)
            % hObject    handle to slider10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
        end
        
        function slider10_CreateFcn(app, hObject, eventdata, h)
            % hObject    handle to slider10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider9_Callback(app, hObject, eventdata, h)
            % hObject    handle to slider9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % h    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
        end
        
        function slider9_CreateFcn(app, hObject, eventdata, h)
            % hObject    handle to slider9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Vesicle_finding_GUI_OpeningFcn(app)
%             
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % h    structure with h and user data (see GUIDATA)
%             % varargin   command line arguments to Vesicle_finding_GUI (see VARARGIN)
%             %%%%% always show this main window in the center
%             %pixels
%             
%             set( gcf, 'Units', 'pixels' );
%             
%             %get your display size
%             % screenSize = get(0, 'ScreenSize');
%             
%             %calculate the center of the display
%             % position = get( gcf, 'Position' );
%             % position(1) = (screenSize(3)-position(3))/2;
%             % position(2) = (screenSize(4)-position(4))/2-30;
%             %
%             % %center the window
%             % set( gcf, 'Position', position );
%             
%             % set the button figures
%             set(h.pushbutton_nextName,'cdata',arrowbutton);
%             set(h.pushbutton_formerName,'cdata',inarrowbutton);
%             
%             
%             % initiate some data of h and some signs
%             
%             % Context variables that are stored in a file
%             amPars.n0=256;  % Automask parameters
%             amPars.thresh=.1;
%             amPars.var=0;
%             amPars.width=0;
%             amPars.edge=.5;
%             amPars.denseFilt=.0015;  % A^-1
%             amPars.dense=.5;
%             
%             sav.automaskPars=amPars;  % saved variables, stored in "VFContext.mat"
%             sav.fullInfoPath='';
%             sav.baseName='';
%             sav.vesicleAmps=[2e-3 5e-3 0];
%             sav.vesicleRadii=[100 300 10];
%             sav.filterFreqs=[0 .1];   % HP and LP, in A^-1
%             sav.contrastPars=zeros(2,1);
%             sav.membranePars=[1.6 60 6];
%             sav.beamPars=[0 0 100];  % X, Y and R
%             sav.black=.6;
%             sav.white=1.2;
%             sav.automaskFixed=0;
%             % sav.initTheVesicles=0;
%             sav.eraseOldPicks=0; % i.e don't load the old vesicles, but delete them.
%             sav.startPath='';
%             sav.startFile='';
%             
%             % State variables
%             h.sav=sav;
%             
%             disp(['The current path is ' pwd]);
%             
%             % Use a context file to get the parameters h.sav
%             % First, find our source directory
%             pa=fileparts(which('Vesicle_finding_GUI'));
%             ourContextName=[pa '/VFContext.mat'];
%             if numel(varargin)>0 && isa(varargin(1),'cell') % batch mode!
%                 h.batchMode=true;
%                 h.jpegDir='Jpeg/';
%                 CheckAndMakeDir(h.jpegDir,1);
%                 h.fileList=varargin{1};
%                 h.fileIndex=1;
%                 disp([num2str(numel(h.fileList)) ' files to process.']);
%                 if numel(varargin)>1 % attempt to use our given context file
%                     if exist(varargin{2},'file')
%                         ourContextName=varargin{2};
%                     else
%                         disp([varargin{2} '  (context file) not found.']);
%                     end;
%                 end;
%             else
%                 h.batchMode=false;
%                 h.fileIndex=0;
%                 h.fileList={};
%             end;
%             % Load the context file
%             if exist(ourContextName,'file')
%                 disp(['Loading ' ourContextName]);
%                 sav1=load([pa '/VFContext.mat']);
%             %     Overwrite the default settings
%                 h.sav=SetDefaultValues(h.sav,sav1.sav);
%                 %%%%%%
%             %     h.sav.initTheVesicles=0;
%             else
%                 disp(['Doesn''t exist:  ' ourContextName]);
%             end;
%             
%             h.imageLoaded=false;
%             h.readOnly=false;
%             h.pixA=1;
%             h.origImage=single(0);  % Original merged image
%             h.rawImage=single(0);   % merged image rescaled to display size
%             h.filtImage=single(0);  % rawImage after low and high pass filtering
%             % h.rawVesImage=single(0);
%             h.goodVesImage=single(0);
%             h.badVesImage=single(0);
%             h.ctf=single(0);
%             h.filtVesImage=single(0);
%             h.ccVals=0;     % values of cc maxima
%             h.ccValsScaled=0;
%             % h.ccRadii=0;    % est. radius corresponding to cc maximum.
%             h.displayMode=0;  % show the raw data
%             h.maxDisplayMode=3;
%             h.oldFilterFreqs=[0 0];
%             h.maskIndex=3;       % default uses existing masks.
%             h.vesModels=single(0);
%             h.ds=1;
%             h.miChanged=false;
%             h.mi=struct;
%             h.miOriginal=struct;
%             h.miChanged=0;
%             h.automaskOn=false;
%             h.varianceMap=single(0);
%             h.axes1ButtonDown=false;
%             h.markedVesicleIndex=0;
%             h.findInMask=1;
%             % h.eraseOldPicks=0;
%             h.borderFraction=256;  % relative size of merged-image border is 1/256 ------------
%             h.theImage=[];
%             h.manualMaskDiameter=0;
%             h.manualMaskActive=false;
%             h.manualMaskCoords=zeros(0,'single');
%             h.oldVesicleModel=[];
%             h.e1CtrValue=0;
%             h.useFirstExposure=1;  % Flag to ignore first exposure in doing masking.
%             h.doTrackMembranes=1;
%             h.roboTrackMembranes=1;
%             h.makeModelVesicles=1;
%             
%             % if ~isfield(h,'automaskBeamOn')
%             h.automaskBeamOn=0;  % don't change this if alreay defined.
%             % end;
%             pos=get(h.axes1,'position');
%             h.displaySize=pos(3:4);
%             h.displaySize=960;
%             set(gcf,'WindowButtonMotionFcn',@WindowButtonMotionFcn);
%             set(gcf,'WindowButtonUpFcn',@WindowButtonUpFcn);
%             
%             % h for the image display
%             h.hSP=0;
%             h.hNav=0;
%             h.ih=0;
%             % h.sav.automaskPars
%             
%             % disp(['The current path is ' pwd]);
%             % % Read a context file to get the parameters h.sav
%             % % First, find our source directory
%             % pa=fileparts(which('Vesicle_finding_GUI'));
%             % ourContextName=[pa '/VFContext.mat'];
%             % if h.batchMode && numel(varargin)>1 % attempt to use our given context file
%             %     if exist(varargin{2},'file')
%             %         ourContextName=varargin{2};
%             %     else
%             %         disp([varargin{2} '  (context file) not found.']);
%             %     end;
%             % end;
%             % if exist(ourContextName,'file')
%             %     disp(['Loading ' ourContextName]);
%             %     sav=load([pa '/VFContext.mat']);
%             %     h.sav=sav.sav;
%             %     %%%%%%
%             %     h.sav.initTheVesicles=0;
%             % end;
%             
%             % set the slider parameters
%             set(h.slider_Threshold,'Min', 0);
%             set(h.slider_Threshold,'Max', 1);
%             set(h.slider_Threshold,'SliderStep',[.1 .2]);
%             set(h.slider_Threshold,'Value',h.sav.automaskPars.thresh);
%             
%             set(h.slider_GlobalVar,'Min', 0);
%             set(h.slider_GlobalVar,'Max', 1);
%             set(h.slider_GlobalVar,'SliderStep',[.1 .2]);
%             set(h.slider_GlobalVar,'Value',h.sav.automaskPars.var);
%             
%             set(h.fixedCheckbox,'Value',h.sav.automaskFixed);
%             
%             set(h.slider_Local,'Min', 0);
%             set(h.slider_Local,'Max', 1);
%             set(h.slider_Local,'SliderStep',[.1 .2]);
%             set(h.slider_Local,'Value',h.sav.automaskPars.width);
%             
%             set(h.slider_Edges,'Min', 0);
%             set(h.slider_Edges,'Max', 1);
%             set(h.slider_Edges,'SliderStep',[.1 .2]);
%             set(h.slider_Edges,'Value',h.sav.automaskPars.edge);
%             
%             set(h.slider_dense,'Min', 0);
%             set(h.slider_dense,'Max', 1);
%             set(h.slider_dense,'SliderStep',[.1 .2]);
%             set(h.slider_dense,'Value',h.sav.automaskPars.dense);
%             
%             
%             % set the membrane parameters
%             % set(h.edit_Edge,'String',sprintf('%1.1f',h.sav.membranePars(3)));
%             % set(h.edit_Thickness,'String',sprintf('%d',h.sav.membranePars(2)));
%             
%             set(h.edit_MinRadius,'String',sprintf('%d',h.sav.vesicleRadii(1)));
%             set(h.edit_MaxRadius,'String',sprintf('%d',h.sav.vesicleRadii(2)));
%             set(h.edit_MinAmp,'String',num2str(1000*h.sav.vesicleAmps(1)));
%             set(h.edit_MaxAmp,'String',num2str(1000*h.sav.vesicleAmps(2)));
%             set(h.edit_IceAmp,'String',num2str(1000*h.sav.vesicleAmps(3)));
%             
%             set(h.edit_RadiusStep,'String',sprintf('%d',h.sav.vesicleRadii(3)));
%             
%             set(h.editWhite,'String',sprintf('%d',h.sav.white));
%             set(h.editBlack,'String',sprintf('%d',h.sav.black));
%             
%             set(h.edit_Highpass,'string',num2str(h.sav.filterFreqs(1)));
%             set(h.edit_Lowpass,'string',num2str(h.sav.filterFreqs(2)));
%             set(h.edit_beamX,'string',num2str(h.sav.beamPars(1)));
%             set(h.edit_beamY,'string',num2str(h.sav.beamPars(2)));
%             set(h.edit_beamR,'string',num2str(h.sav.beamPars(3)));
%             % h.sav.findInMask=1; %%%%
%             set(h.FindInMaskButton,'value',h.sav.findInMask);
%             % h.sav.eraseOldPicks=0; %%%%
%             set(h.EraseOldPicksButton,'value',h.sav.eraseOldPicks);
%             set(h.textFilename,'string','---');
%             
%             h.output = hObject;
%             % h.altBasePath='';  % mi.basePath value if the original one doesn't work.
%             h.imageFileTypes={'m.mrc' 'mf.mrc' 'm.jpg' 'mf.jpg'};  % allowed types of
%             % images to load based on mi file.
%             
%             set(h.MaskRadiobuttons,'SelectedObject',[]);
%             
%             % Update h structure
%             % UIWAIT makes Vesicle_finding_GUI wait for user response (see UIRESUME)
%             % uiwait(h.figure1);
%             
%             if ~h.batchMode
%                 h=GetFileList(h);
%             end;
%             h=LoadAFile(h);
%             set(h.togglebutton_RoboFit,'value',h.batchMode); % start RoboFit automatically in batch
%             guidata(hObject, h);
%             
%             if h.batchMode
%                 doRobofit(hObject,h);
%                 disp(' ');
%                 disp('Vesicle_finding_GUI done.');
%                 delete(hObject); % remove the GUI
%             end;
        end

        % Value changed function: editBlack
        function editBlack_Callback(app, event)
            q=str2double(get(hObject,'String'));
            if ~isnan(q)
                h.sav.black=q;
                set(hObject,'string',num2str(q));
                h=ShowImage(h);
                guidata(hObject, h);
            end;
        end

        % Value changed function: editWhite
        function editWhite_Callback(app, event)
            q=str2double(get(hObject,'String'));
            if ~isnan(q)
                h.sav.white=q;
                set(hObject,'string',num2str(q));
                h=ShowImage(h);
                guidata(hObject, h);
            end;
        end

        % Value changed function: edit_Highpass
        function edit_Highpass_Callback(app, event)
            % Hints: get(hObject,'String') returns contents of edit_Highpass as text
            q=str2double(get(hObject,'String'));
            if ~isnan(q)
                if q<1e-6
                    q=0;
                end;
                h.sav.filterFreqs(1)=q;
                set(hObject,'string',num2str(q));
                h=UpdateDisplayFiltering(h);
                h=ShowImage(h);
                guidata(hObject, h);
            end;
        end

        % Value changed function: edit_IceAmp
        function edit_IceAmp_Callback(app, event)
            val=str2double(get(hObject,'String')); % returns contents of edit_IceAmp as a double
            if val > -1000 && val < 1000
                h.sav.vesicleAmps(3)=val*1e-3;
                guidata(hObject,h);
            end;
        end

        % Value changed function: edit_Lowpass
        function edit_Lowpass_Callback(app, event)
            q=str2double(get(hObject,'String'));
            if ~isnan(q)
                if q<1e-4 || q>1
                    q=0;
                end;
                h.sav.filterFreqs(2)=q;
                set(hObject,'string',num2str(q));
                h=UpdateDisplayFiltering(h);
                h=ShowImage(h);
                guidata(hObject, h);
            
            end
        end

        % Value changed function: edit_MaxAmp
        function edit_MaxAmp_Callback(app, event)
            val=str2double(get(hObject,'String')); % returns contents of edit_MinRadius as a double
            if val > 0 && val < 1000
                h.sav.vesicleAmps(2)=val*1e-3;
                guidata(hObject,h);
            end;
        end

        % Value changed function: edit_MaxRadius
        function edit_MaxRadius_Callback(app, event)
            val=str2double(get(hObject,'String')); % returns contents of edit_MinRadius as a double
            if val > 100 && val < 2000
                h.sav.vesicleRadii(2)=val;
                guidata(hObject,h);
            end;
        end

        % Value changed function: edit_MinAmp
        function edit_MinAmp_Callback(app, event)
            val=str2double(get(hObject,'String')); % returns contents of edit_MinRadius as a double
            if val > 0 && val < 1000
                h.sav.vesicleAmps(1)=val*1e-3;
                guidata(hObject,h);
            end;
        end

        % Value changed function: edit_MinRadius
        function edit_MinRadius_Callback(app, event)
            val=str2double(get(hObject,'String')); % returns contents of edit_MinRadius as a double
            if val > 20 && val < 1000
                h.sav.vesicleRadii(1)=val;
                guidata(hObject,h);
            end;
        end

        % Value changed function: edit_RadiusStep
        function edit_RadiusStep_Callback(app, event)
            val=str2double(get(hObject,'String')); % returns contents of edit_MinRadius as a double
            if val > 1 && val < 100
                h.sav.vesicleRadii(3)=val;
                guidata(hObject,h);
            end;
        end

        % Value changed function: edit_beamR
        function edit_beamR_Callback(app, event)
            q=str2double(get(hObject,'String'));
            if ~isnan(q)
                h.sav.beamPars(3)=q;
                set(hObject,'string',num2str(q));
                h=UpdateAutomaskBeam(h);
                guidata(hObject, h);
            end;
        end

        % Value changed function: edit_beamX
        function edit_beamX_Callback(app, event)
            q=str2double(get(hObject,'String'));
            if ~isnan(q)
                h.sav.beamPars(1)=q;
                set(hObject,'string',num2str(q));
                h=UpdateAutomaskBeam(h);
                guidata(hObject, h);
            end;
        end

        % Value changed function: edit_beamY
        function edit_beamY_Callback(app, event)
            q=str2double(get(hObject,'String'));
            if ~isnan(q)
                h.sav.beamPars(2)=q;
                set(hObject,'string',num2str(q));
                h=UpdateAutomaskBeam(h);
                guidata(hObject, h);
            end;
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            CloseFile(h);
            h.miChanged=0;
            rsFindVesicles3('end');  % purge the variables
            pa=AddSlash(fileparts(which('Vesicle_finding_GUI')));
            % Save parameters from VesiclePara.mat in the local directory
            sav=h.sav;
            sav.startPath=pwd;
            sav.startFile='';
            if h.fileIndex>0 && numel(h.fileList)>0
                sav.startFile=h.fileList{min(h.fileIndex,end)};
            end;
            contextFilename='VFContext.mat';
            contextArchiveName=[pa contextFilename];
            disp(' ');
            try
                disp(['saving ' contextFilename]);
                save(contextFilename,'sav');
            catch
                disp('  ...unsuccessful!');
            end;
            try
                disp(['saving ' contextArchiveName]);
                save(contextArchiveName,'sav');
            catch
                disp('  ...unsuccessful!');
            end
            
            disp('--Vesicle finder done.');
            disp(' ');
            delete(hObject);
        end

        % Value changed function: fixedCheckbox
        function fixedCheckbox_Callback(app, event)
            h.sav.automaskFixed=get(hObject,'value');
            h=CreateE1Map(h);
            if h.automaskOn
                h=NewAutomask(h);
            end;
            guidata(hObject,h);
        end

        % Button pushed function: pushbutton22
        function pushbutton22_Callback(app, event)
            disp('pushbutton22');
            % hObject    handle to pushbutton22 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
        end

        % Button pushed function: pushbutton_AutoContrast
        function pushbutton_AutoContrast_Callback(app, event)
            % theImage =  repmat(rot90(256*(imData-midValue-h.sav.black)/(h.sav.white-h.sav.black)+128),[1 1 3]);
            [~,mulr,addr]=imscale(h.filtImage,1,[4.5 5.5]);
            h.sav.black=(.5-addr)/mulr;
            h.sav.white=(1.5-addr)/mulr;
            set(h.editWhite,'String',sprintf('%3d',h.sav.white));
            set(h.editBlack,'String',sprintf('%3d',h.sav.black));
            h=ShowImage(h);
            guidata(hObject,h);
        end

        % Button pushed function: pushbutton_FindAndTrack
        function pushbutton_FindAndTrack_Callback(app, event)
            h.doTrackMembranes=1;
            DoFind(hObject,eventdata,h);
        end

        % Button pushed function: pushbutton_FindMore
        function pushbutton_FindMore_Callback(app, event)
            if ~h.imageLoaded
                return
            end;
            
            set(h.figure1,'pointer','watch');
            % membrane model
            vLipid=h.sav.membranePars(1);
            thk=h.sav.membranePars(2);
            rise=h.sav.membranePars(3);
            pixA=h.mi.pixA;
            % Create the model, which is sampled in units of the original pixel size.
            nm0=ceil(thk/(2*pixA))*2+3;  % array for vesicle model; 60A nominal
            if ~isfield(h.mi,'vesicleModel') || numel(h.mi.vesicleModel)<3
                h.mi.vesicleModel=fuzzymask(nm0,1,thk/pixA/2,rise/pixA)...
                    *vLipid;  % units of V
            end;
            rPars=h.sav.vesicleRadii;
            mi1=rsFindVesicles3(h.rawImage-h.rawVesImage, h.mi, rPars, h.findInMask);
            prevNFound=numel(mi1.vesicle.x);
            %%
            minAmp=h.sav.vesicleAmps(1);
            maxAmp=h.sav.vesicleAmps(2);
            mins=1;
            nVesOld=0;
            % Loop through finding groups of 50 vesicles.
            while mins>minAmp
                [mi1, t]=rsFindVesicles3('next',50,h.sav.vesicleAmps);
                axes(h.axes1);
                ih = imshow(rot90(uint8(imscale(t.ms-t.umodel,256,1e-3))),...
                    'InitialMagnification',100*size(t.ms,1)/size(h.rawImage,1) );
                %     drawnow;
                mins=t.globalmax;
                nves=size(mi1.vesicle.s,1);
                set(hObject,'string',num2str(nves));
                %     Make the scatterplot of vesicle radii and amplitudes
                xs1=rPars(1);
                xs2=rPars(2);
                xs=[xs1    xs1    xs2    xs2    xs1];
                ys=[minAmp maxAmp maxAmp minAmp minAmp];
                if numel(mi1.vesicle.ok)<4  % not even one row present
                    mi1.vesicle.ok=false(1,4); % make sure there are enough elements
                end;
                goodVes=all(mi1.vesicle.ok(1:nves,1:2),2);
                badVes=mi1.vesicle.ok(1:nves,1) & ~mi1.vesicle.ok(1:nves,2);
                %     plot(h.axes3,mi1.vesicle.r(1:nves)*mi1.pixA,mi1.vesicle.s(1:nves),...
                %         'b.', xs,ys,'r-','markersize',10);
                if numel(mi1.vesicle.r)>0  % something to draw
                    plot(h.axes3,mi1.vesicle.r(goodVes,1)*mi1.pixA,mi1.vesicle.s(goodVes,1),'b.',...
                        mi1.vesicle.r(badVes,1)*mi1.pixA,mi1.vesicle.s(badVes,1),'m.',...
                        xs,ys,'k-','markersize',15);
                else
                    cla(h.axes3);
                end;
                title(nves);
                %     Exit the loop when we can't find any more vesicles
                if nves<=nVesOld
                    break
                end;
                nVesOld=nves;
            end;
            h.ccVals=t.ccsmx+h.ccVals;
            h.ccValsScaled=t.ccsmxScaled+h.ccValsScaled;
            h.ccRadii=(t.fitmin+(t.ccsmi-1)*t.rstep)*t.ds;  % radius in orig pixels
            
            mi1.vesicle.shiftX=[];
            mi1.vesicle.shiftY=[];
            mi1.vesicle.shiftOk=[];
            mi1.vesicle.af=[];
            mi1.vesicle.refined=0;
            mi1.vesicle.extraPeaks=[];
            mi1.vesicle.extraSD=0;
            mi1.vesicle.extraS=[];
            
            % Additional vesicles are marked 'false' in the 4th column.
            totalNFound=numel(mi1.vesicle.x);
            if totalNFound>prevNFound
                disp([num2str(totalNFound-prevNFound) ' additional vesicles found...']);
                mi1.vesicle.ok(prevNFound+1:totalNFound,4)=false;
            end;
            
            h.mi=mi1;
            h.miChanged=1;
            if h.makeModelVesicles
                h.goodVesImage=meMakeModelVesicles(h.mi,size(h.rawImage),find(goodVes));
                h.badVesImage=meMakeModelVesicles(h.mi,size(h.rawImage),find(badVes));
                h.rawVesImage=h.goodVesImage+h.badVesImage;
                % h.rawVesImage=meMakeModelVesicles(h.mi,size(h.rawImage));
                h=UpdateDisplayFiltering(h);
            end;
            % h.displayMode=0;  % subtract and mark the vesicles
            h.markedVesicleIndex=0;
            ShowImage(h);
            guidata(hObject,h);
            set(hObject,'string','Find');
            set(h.figure1,'pointer','arrow');
            % disp('Done.');
        end

        % Button pushed function: pushbutton_Find
        function pushbutton_Find_Callback(app, event)
            h.doTrackMembranes=0;
            DoFind(hObject,eventdata,h);
        end

        % Button pushed function: pushbutton_Intersect
        function pushbutton_Intersect_Callback(app, event)
            % hObject    handle to pushbutton_Intersect (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % h    structure with h and user data (see GUIDATA)
            switch h.mi.mask(h.maskIndex).merge
                case 'AND'
                    h.mi.mask(h.maskIndex).merge='OR';
                case 'OR'
                    h.mi.mask(h.maskIndex).merge='AND';
            end;
            h.miChanged=1;
            h=ShowImage(h);
            guidata(hObject, h);
        end

        % Button pushed function: pushbutton_Invert
        function pushbutton_Invert_Callback(app, event)
            % hObject    handle to pushbutton_Invert (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % h    structure with h and user data (see GUIDATA)
            msk=meGetMask(h.mi,0,h.maskIndex);  % get mask at native size.
            h.mi=meInsertMask(~msk,h.mi,h.maskIndex,...
                h.mi.mask(h.maskIndex).merge);
            h.miChanged=1;
            h=ShowImage(h);
            guidata(hObject, h);
        end

        % Button pushed function: pushbutton_LoadFile
        function pushbutton_LoadFile_Callback(app, event)
            % We'll load a file list, and read the selected mi file
            CloseFile(h);
            h.sav.startFile=''; % Force the file selector to be used.
            disp('LoadFile');
            h=GetFileList(h);
            [h,ok]=LoadAFile(h);
            if ~ok
                disp('File couldn''t be loaded.');
                guidata(hObject,h);
                return
            end;
            h=LoadAFile(h);
            guidata(hObject, h);
        end

        % Value changed function: pushbutton_OutlineMask
        function pushbutton_OutlineMask_Callback(app, event)
            % Use the stored property 'value' in the axes1 button down function to
            % actually initiate the manual mask input.
            active=get(hObject,'value');
            h.outlineMaskActive=active;
            if ~active
                return
            end;
            if ~h.imageLoaded
                set(h.pushbutton_OutlineMask,'value',0);
            elseif ~isfield(h.mi,'mask')
                h.mi.mask=struct('merge',[],'encoding',[],'data',[]);
            end;
            q=get(h.pushbutton_OutlineMask,'value');
            if h.imageLoaded && q % We're doing manual masking
                %         try
                hFH = imfreehand();
                %     binaryImage =~rot90(hFH.createMask(h.ih),3);
                binaryImage=createMask(hFH,h.ih);
                delete(hFH);  % needed for bug in v2015a prerelease
                binaryImage =~rot90(binaryImage,3);
                h.maskIndex=max(3,h.maskIndex)+1;
                h.mi=meInsertMask(binaryImage,h.mi,h.maskIndex,'AND');
                h.miChanged=1;
                h=ShowImage(h);
                %         catch   % Error occurred, exit manual mask mode.
                %  disp('error in freehand');
                %             set(h.pushbutton_OutlineMask,'value',0);
                %             return
                %         end;
            
                % Create a binary image ("mask") from the ROI object.
                set(h.pushbutton_OutlineMask,'value',0);
                h.outlineMaskActive=0;
            end;
            guidata(hObject, h);
        end

        % Button pushed function: pushbutton_Redo
        function pushbutton_Redo_Callback(app, event)
            h.maskIndex=min(h.maskIndex+1,numel(h.mi.mask));
            h=ShowImage(h);
            guidata(hObject, h);
        end

        % Button pushed function: pushbutton_ShowVesicles
        function pushbutton_ShowVesicles_Callback(app, event)
            h.displayMode=h.displayMode+1;
            if h.displayMode>h.maxDisplayMode
                h.displayMode=0;
            end;
            h=ShowImage(h);
            guidata(hObject,h);
        end

        % Button pushed function: pushbutton_Undo
        function pushbutton_Undo_Callback(app, event)
            h.maskIndex=max(1,h.maskIndex-1);
            h=ShowImage(h);
            guidata(hObject, h);
        end

        % Button pushed function: pushbutton_formerName
        function pushbutton_formerName_Callback(app, event)
                h.fileIndex=h.fileIndex-1;
                h=LoadAFile(h);
                guidata(hObject,h);
        end

        % Button pushed function: pushbutton_nextName
        function pushbutton_nextName_Callback(app, event)
                h.fileIndex=h.fileIndex+1;
                h=LoadAFile(h);
                guidata(hObject,h);
        end

        % Value changed function: slider_Edges
        function slider_Edges_Callback(app, event)
            h.sav.automaskPars.edge=get(h.slider_Edges,'value');
            h=NewAutomask(h);
            guidata(hObject, h);
        end

        % Value changed function: slider_GlobalVar
        function slider_GlobalVar_Callback(app, event)
            h.sav.automaskPars.var=get(h.slider_GlobalVar,'value');
            h=NewAutomask(h);
            guidata(hObject, h);
        end

        % Value changed function: slider_Local
        function slider_Local_Callback(app, event)
            h.sav.automaskPars.width=get(h.slider_Local,'value');
            h=NewAutomask(h);
            guidata(hObject, h);
        end

        % Value changed function: slider_Threshold
        function slider_Threshold_Callback(app, event)
            h.sav.automaskPars.thresh=get(h.slider_Threshold,'value');
            h=NewAutomask(h);
            guidata(hObject, h);
        end

        % Value changed function: slider_dense
        function slider_dense_Callback(app, event)
            h.sav.automaskPars.dense=get(h.slider_dense,'value');
            h=NewAutomask(h);
            guidata(hObject,h);
        end

        % Value changed function: togglebutton_Automask
        function togglebutton_Automask_Callback(app, event)
            h.automaskOn=get(h.togglebutton_Automask,'value') && h.imageLoaded;
            if h.automaskOn  % First, compute the inverse-filtered images
                h=InitAutomask(h);
                h=NewAutomask(h);
            else
                h=NewAutomask(h,false);
            end;
            guidata(hObject, h);
        end

        % Value changed function: togglebutton_Beam
        function togglebutton_Beam_Callback(app, event)
            active=get(h.togglebutton_Beam,'value') && h.imageLoaded;
            if active
                h.maskIndex=max(h.maskIndex,2);  % includes the beam at least.
            end;
            h=UpdateAutomaskBeam(h,active);
            guidata(hObject, h);
        end

        % Value changed function: togglebutton_PaintMask
        function togglebutton_PaintMask_Callback(app, event)
            active=get(hObject,'value');
            h.manualMaskActive=active;
            guidata(hObject,h);
        end

        % Value changed function: togglebutton_RoboFit
        function togglebutton_RoboFit_Callback(app, event)
            active=get(hObject,'value');
            if active
                h.fileIndex=h.fileIndex+1;
                [h,ok]=LoadAFile(h);
                if ok
                    doRobofit(hObject,h);
                    guidata(hObject,h);
                end;
            else
                disp('----RoboFit stopping----');
            end;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Vesicle_finding_GUI';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.Main_im = uipanel(app.figure1);
            app.Main_im.Title = ' ';
            app.axes3 = uiaxes(app.Main_im);
            app.axes1 = uiaxes(app.Main_im);
            app.axes1.XTickLabel = ' ';
            app.axes1.YTickLabel = '';
            app.uipanel24 = uipanel(app.Main_im);
            app.uipanel24.Title = 'Data';
            app.text_Highpass = uilabel(app.uipanel24);
            app.text_Highpass.Text = 'Highpass   (1/Å)   Lowpass';
            app.edit_Highpass = uieditfield(app.uipanel24, 'text');
            app.edit_Highpass.ValueChangedFcn = createCallbackFcn(app, @edit_Highpass_Callback, true);
            app.edit_Highpass.Value = 'Edit Text';
            app.pushbutton_nextName = uibutton(app.uipanel24, 'push');
            app.pushbutton_nextName.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_nextName_Callback, true);
            app.pushbutton_nextName.Text = ' ';
            app.pushbutton_formerName = uibutton(app.uipanel24, 'push');
            app.pushbutton_formerName.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_formerName_Callback, true);
            app.pushbutton_formerName.Text = ' ';
            app.pushbutton_LoadFile = uibutton(app.uipanel24, 'push');
            app.pushbutton_LoadFile.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_LoadFile_Callback, true);
            app.pushbutton_LoadFile.Text = 'Load File';
            app.edit_Lowpass = uieditfield(app.uipanel24, 'text');
            app.edit_Lowpass.ValueChangedFcn = createCallbackFcn(app, @edit_Lowpass_Callback, true);
            app.edit_Lowpass.Value = 'Edit Text';
            app.textFilename = uilabel(app.uipanel24);
            app.textFilename.Text = '---';
            app.uipanel23 = uipanel(app.Main_im);
            app.uipanel23.Title = 'Masking';
            app.slider_Edges = uislider(app.uipanel23);
            app.slider_Edges.Limits = [0 1];
            app.slider_Edges.MajorTicks = [];
            app.slider_Edges.ValueChangedFcn = createCallbackFcn(app, @slider_Edges_Callback, true);
            app.slider_Edges.MinorTicks = [];
            app.slider_Local = uislider(app.uipanel23);
            app.slider_Local.Limits = [0 1];
            app.slider_Local.MajorTicks = [];
            app.slider_Local.ValueChangedFcn = createCallbackFcn(app, @slider_Local_Callback, true);
            app.slider_Local.MinorTicks = [];
            app.slider_Threshold = uislider(app.uipanel23);
            app.slider_Threshold.Limits = [0 1];
            app.slider_Threshold.MajorTicks = [];
            app.slider_Threshold.ValueChangedFcn = createCallbackFcn(app, @slider_Threshold_Callback, true);
            app.slider_Threshold.MinorTicks = [];
            app.text31 = uilabel(app.uipanel23);
            app.text31.Text = 'Border';
            app.text32 = uilabel(app.uipanel23);
            app.text32.Text = 'Local';
            app.text33 = uilabel(app.uipanel23);
            app.text33.Text = 'Smoothness';
            app.togglebutton_Automask = uibutton(app.uipanel23, 'state');
            app.togglebutton_Automask.ValueChangedFcn = createCallbackFcn(app, @togglebutton_Automask_Callback, true);
            app.togglebutton_Automask.Text = 'Automask';
            app.pushbutton_OutlineMask = uibutton(app.uipanel23, 'state');
            app.pushbutton_OutlineMask.ValueChangedFcn = createCallbackFcn(app, @pushbutton_OutlineMask_Callback, true);
            app.pushbutton_OutlineMask.Text = 'Outline mask';
            app.pushbutton_Invert = uibutton(app.uipanel23, 'push');
            app.pushbutton_Invert.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Invert_Callback, true);
            app.pushbutton_Invert.Text = 'Invert';
            app.pushbutton_Intersect = uibutton(app.uipanel23, 'push');
            app.pushbutton_Intersect.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Intersect_Callback, true);
            app.pushbutton_Intersect.Text = 'Intersect';
            app.pushbutton_Undo = uibutton(app.uipanel23, 'push');
            app.pushbutton_Undo.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Undo_Callback, true);
            app.pushbutton_Undo.Text = 'Undo';
            app.pushbutton_Redo = uibutton(app.uipanel23, 'push');
            app.pushbutton_Redo.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Redo_Callback, true);
            app.pushbutton_Redo.Text = 'Redo';
            app.togglebutton_Beam = uibutton(app.uipanel23, 'state');
            app.togglebutton_Beam.ValueChangedFcn = createCallbackFcn(app, @togglebutton_Beam_Callback, true);
            app.togglebutton_Beam.Text = 'Beam';
            app.MaskRadiobuttons = uibuttongroup(app.uipanel23);
            app.radiobutton22 = uiradiobutton(app.MaskRadiobuttons);
            app.radiobutton22.Text = '300Å';
            app.radiobutton22.Value = true;
            app.radiobutton24 = uiradiobutton(app.MaskRadiobuttons);
            app.radiobutton24.Text = '200Å disc';
            app.radiobutton23 = uiradiobutton(app.MaskRadiobuttons);
            app.radiobutton23.Text = '400Å';
            app.slider_dense = uislider(app.uipanel23);
            app.slider_dense.Limits = [0 1];
            app.slider_dense.MajorTicks = [];
            app.slider_dense.ValueChangedFcn = createCallbackFcn(app, @slider_dense_Callback, true);
            app.slider_dense.MinorTicks = [];
            app.text38 = uilabel(app.uipanel23);
            app.text38.Text = 'Global';
            app.edit_beamX = uieditfield(app.uipanel23, 'text');
            app.edit_beamX.ValueChangedFcn = createCallbackFcn(app, @edit_beamX_Callback, true);
            app.edit_beamX.Value = 'Edit Text';
            app.edit_beamY = uieditfield(app.uipanel23, 'text');
            app.edit_beamY.ValueChangedFcn = createCallbackFcn(app, @edit_beamY_Callback, true);
            app.edit_beamY.Value = 'Edit Text';
            app.edit_beamR = uieditfield(app.uipanel23, 'text');
            app.edit_beamR.ValueChangedFcn = createCallbackFcn(app, @edit_beamR_Callback, true);
            app.edit_beamR.Value = 'Edit Text';
            app.text40 = uilabel(app.uipanel23);
            app.text40.Text = 'X';
            app.text41 = uilabel(app.uipanel23);
            app.text41.Text = 'Y';
            app.text42 = uilabel(app.uipanel23);
            app.text42.Text = 'R';
            app.togglebutton_PaintMask = uibutton(app.uipanel23, 'state');
            app.togglebutton_PaintMask.ValueChangedFcn = createCallbackFcn(app, @togglebutton_PaintMask_Callback, true);
            app.togglebutton_PaintMask.Text = 'Paint mask';
            app.fixedCheckbox = uicheckbox(app.uipanel23);
            app.fixedCheckbox.ValueChangedFcn = createCallbackFcn(app, @fixedCheckbox_Callback, true);
            app.fixedCheckbox.Tooltip = 'freezes the global mask reference';
            app.fixedCheckbox.Text = 'fix';
            app.text46 = uilabel(app.uipanel23);
            app.text46.Text = 'Var';
            app.slider_GlobalVar = uislider(app.uipanel23);
            app.slider_GlobalVar.Limits = [0 1];
            app.slider_GlobalVar.MajorTicks = [];
            app.slider_GlobalVar.ValueChangedFcn = createCallbackFcn(app, @slider_GlobalVar_Callback, true);
            app.slider_GlobalVar.MinorTicks = [];
            app.uipanel7 = uipanel(app.Main_im);
            app.uipanel7.Title = 'Automatic finder';
            app.edit_MinRadius = uieditfield(app.uipanel7, 'text');
            app.edit_MinRadius.ValueChangedFcn = createCallbackFcn(app, @edit_MinRadius_Callback, true);
            app.edit_MinRadius.Value = 'Edit Text';
            app.text28 = uilabel(app.uipanel7);
            app.text28.Text = 'Max R';
            app.text_MinRadius = uilabel(app.uipanel7);
            app.text_MinRadius.Text = 'Min R';
            app.pushbutton_Find = uibutton(app.uipanel7, 'push');
            app.pushbutton_Find.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Find_Callback, true);
            app.pushbutton_Find.Text = 'Find';
            app.edit_MaxRadius = uieditfield(app.uipanel7, 'text');
            app.edit_MaxRadius.ValueChangedFcn = createCallbackFcn(app, @edit_MaxRadius_Callback, true);
            app.edit_MaxRadius.Value = 'Edit Text';
            app.edit_RadiusStep = uieditfield(app.uipanel7, 'text');
            app.edit_RadiusStep.ValueChangedFcn = createCallbackFcn(app, @edit_RadiusStep_Callback, true);
            app.edit_RadiusStep.Value = 'Edit Text';
            app.text36 = uilabel(app.uipanel7);
            app.text36.Text = 'Min amp';
            app.text37 = uilabel(app.uipanel7);
            app.text37.Text = 'Max Amp';
            app.edit_MinAmp = uieditfield(app.uipanel7, 'text');
            app.edit_MinAmp.ValueChangedFcn = createCallbackFcn(app, @edit_MinAmp_Callback, true);
            app.edit_MinAmp.Value = 'Edit Text';
            app.edit_MaxAmp = uieditfield(app.uipanel7, 'text');
            app.edit_MaxAmp.ValueChangedFcn = createCallbackFcn(app, @edit_MaxAmp_Callback, true);
            app.edit_MaxAmp.Value = 'Edit Text';
            app.text29 = uilabel(app.uipanel7);
            app.text29.Text = 'R step';
            app.pushbutton_FindMore = uibutton(app.uipanel7, 'push');
            app.pushbutton_FindMore.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_FindMore_Callback, true);
            app.pushbutton_FindMore.Text = 'Find Concentric';
            app.text45 = uilabel(app.uipanel7);
            app.text45.Text = 'Ice Amp';
            app.edit_IceAmp = uieditfield(app.uipanel7, 'text');
            app.edit_IceAmp.ValueChangedFcn = createCallbackFcn(app, @edit_IceAmp_Callback, true);
            app.edit_IceAmp.Value = 'Edit Text';
            app.pushbutton_FindAndTrack = uibutton(app.uipanel7, 'push');
            app.pushbutton_FindAndTrack.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_FindAndTrack_Callback, true);
            app.pushbutton_FindAndTrack.Text = 'Find & Track';
            app.pushbutton22 = uibutton(app.Main_im, 'push');
            app.pushbutton22.ButtonPushedFcn = createCallbackFcn(app, @pushbutton22_Callback, true);
            app.pushbutton22.Text = 'Push Button';
            app.togglebutton_RoboFit = uibutton(app.Main_im, 'state');
            app.togglebutton_RoboFit.ValueChangedFcn = createCallbackFcn(app, @togglebutton_RoboFit_Callback, true);
            app.togglebutton_RoboFit.Text = 'RoboFit';
            app.editWhite = uieditfield(app.Main_im, 'text');
            app.editWhite.ValueChangedFcn = createCallbackFcn(app, @editWhite_Callback, true);
            app.editWhite.Value = 'Edit Text';
            app.editBlack = uieditfield(app.Main_im, 'text');
            app.editBlack.ValueChangedFcn = createCallbackFcn(app, @editBlack_Callback, true);
            app.editBlack.Value = 'Edit Text';
            app.text43 = uilabel(app.Main_im);
            app.text43.Text = 'W';
            app.text44 = uilabel(app.Main_im);
            app.text44.Text = 'B';
            app.pushbutton_ShowVesicles = uibutton(app.Main_im, 'push');
            app.pushbutton_ShowVesicles.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_ShowVesicles_Callback, true);
            app.pushbutton_ShowVesicles.Text = 'Show';
            app.pushbutton_AutoContrast = uibutton(app.Main_im, 'push');
            app.pushbutton_AutoContrast.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_AutoContrast_Callback, true);
            app.pushbutton_AutoContrast.Text = 'A';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vesicle_finding_GUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Vesicle_finding_GUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end